classdef ODI_2019_PREDICTOR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WorldCupSimulatorUIFigure      matlab.ui.Figure
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        PerformanceAgainstButtonGroup  matlab.ui.container.ButtonGroup
        AllTeamsButton                 matlab.ui.control.RadioButton
        TheOpponentButton              matlab.ui.control.RadioButton
        WhereDropDownLabel             matlab.ui.control.Label
        WhereDropDown                  matlab.ui.control.DropDown
        TimeSpanDropDownLabel          matlab.ui.control.Label
        TimeSpanDropDown               matlab.ui.control.DropDown
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        GetDataButton                  matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        UIAxes1                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
       %teams Playin In worldcup
       WC_Teams = ["South Africa" "Sri Lanka" "New Zealand" "Bangladesh"...
           "Afghanistan" "India" "England" "Pakistan" "West Indies" "Australia"]';
       Schedule;
       match_index = 0;
       points_table = zeros(10,5);
       tdata  %Table Data for pointer table
       Result_Tab  %Result Table
       Result_Mat  %Result MAtrix
       SFinalist   %Semi finalist
       Finalist1  %Finalist Teams
       Finalist2  %Finalist Teams
       Winner
    end
    
    properties (Access = public)
        DialogApp                          %options dialog app
    
    end
    
    methods (Access = public)
       
        function WCSchedule(app)
            %% Import the data, extracting spreadsheet dates in Excel serial date format
            [~, ~, raw, dates] = xlsread('Data/ScheduleWCup.xlsx','Sheet1', 'A2:F49', '', @convertSpreadsheetExcelDates);
            
            stringVectors = string(raw(:,[3,4,5]));
            stringVectors(ismissing(stringVectors)) = '';
            raw = raw(:,[1,6]);
            dates = dates(:,2);

            %% Create output variable
            data = reshape([raw{:}],size(raw));
            
            %% Create table
            app.Schedule = table;
            
            %Allocate imported array to column variable name
            app.Schedule.Match = data(:,1);
            app.Schedule.Date = datetime([dates{:,1}].', 'ConvertFrom', 'Excel','Format','MMMM d');
            app.Schedule.Team1 = stringVectors(:,1);
            app.Schedule.Team2 = stringVectors(:,2);
            app.Schedule.Venue = stringVectors(:,3);
            app.Schedule.DN = data(:,2);

            clearvars data raw dates stringVectors;
            
        end
           
    end
    
    methods (Access = public)
        
        function PlayMatch(app,i)
            app.match_index = i;
            if app.match_index <= 45
                app.Label2.Text = 'League stage is ON';
                T1 = app.Schedule.Team1(i);
                T2 = app.Schedule.Team2(i);
                app.Label.Text = sprintf('Match #%d: %s vs %s',i,T1,T2);
            elseif app.match_index == 46
                app.Label2.Text = 'Semi Final 1 is being Played';
                T1 = app.SFinalist(1);
                T2 = app.SFinalist(4);
                app.Label.Text = sprintf('Semifinal #1: %s vs %s',T1,T2);
                pause(4);
            elseif app.match_index == 47
                app.Label2.Text = 'Semi Final 2 is being Played';
                T1 = app.SFinalist(1);
                T2 = app.SFinalist(4);
                app.Label.Text = sprintf('Semifinal #2: %s vs %s',T1,T2);
                pause(4);
            elseif app.match_index == 48
                app.Label2.Text = 'world cup is being Played';
                T1 = app.Finalist1;
                T2 = app.Finalist2;
                app.Label.Text = sprintf('Semifinal : %s vs %s',T1,T2);
                pause(5);
            end
            
            T1_num = find(contains(app.WC_Teams,T1),1);
            T2_num = find(contains(app.WC_Teams,T2),1);
            img1 = imread(['Maps/' num2str(T1_num) '.jpg']);
            img2 = imread(['Maps/' num2str(T2_num) '.jpg']);
            imshow(img1, 'Parent',app.UIAxes1);
            imshow(img2, 'Parent',app.UIAxes2);
            app.Lable3.Text = '';
            if isempty(app.Result_Mat)
                T1_chance = app.Result_Tab(T1_num,T2_num);
                T2_chance = app.Result_Tab(T2_num,T1_num);
                T_Tie = 0.02;
            elseif isempty(app.Result_Tab)
                T1_w = app.Result_Mat(T1_num,1);
                T2_w = app.Result_Mat(T2_num,1);
                T1_l = app.Result_Mat(T1_num,2);
                T2_l = app.Result_Mat(T2_num,2);
                T1_t = app.Result_Mat(T1_num,3);
                T2_t = app.Result_Mat(T2_num,3);
                T1_chance = T1_w * T2_l;
                T2_chance = T2_w * T1_l;
                T_Tie = T1_t * T2_t;
            end
            
            vec = [T1_chance T2_chance T_Tie];
            prob = vec/sum(vec);
            labels = {T1,T2,'Tie'};
            explode = [1 1 0];
            pie(prob,explode,labels,'Parent',app.UIAxes3);
            res_out = randsrc(1,1,[1,2,3,;prob]);
            
            if app.match_index <= 45
                if res_out == 1
                    app.Label3.Text = sprintf('Match #%d %s vs %s; Winner: %s',app.match_index,T1,T2,T1);
                    app.points_table(T1_num,2) = app.points_table(T1_num,2) + 1;
                    app.points_table(T1_num,5) = app.points_table(T1_num,5) + 3;
                    app.points_table(T2_num,3) = app.points_table(T2_num,3) + 1;
                elseif res_out == 1
                    app.Label3.Text = sprintf('Match #%d %s vs %s; Winner: %s',app.match_index,T1,T2,T2);
                    app.points_table(T2_num,2) = app.points_table(T2_num,2) + 1;
                    app.points_table(T2_num,5) = app.points_table(T2_num,5) + 3;
                    app.points_table(T1_num,3) = app.points_table(T1_num,3) + 1;
                else 
                    app.Label3.Text = sprintf('Match #%d between %s %s is tie',app.match_index,T1,T2);
                    app.points_table(T1_num,4) = app.points_table(T1_num,4) + 1;
                    app.points_table([T1_num, T2_num],5) = app.points_table([T1_num, T2_num],5) + 1;
                    app.points_table(T2_num,4) = app.points_table(T1_num,3) + 1;
                end
                %increment by 1
                app.points_table([T1_num, T2_num],1) = app.points_table([T1_num, T2_num],1) + 1;
            elseif app.match_index == 46
                while (res_out == 3)
                    res_out = randsrc(1,1,[1,2,3;prob]);
                end
                if res_out == 1
                    app.Label3.Text = sprintf('%s wins',T1);
                    app.Finalist1 = T1;
                    app.Label2.Text = sprintf('Finalist #1: %s',T1);
                elseif res_out == 2
                    app.Label3.Text = sprintf('%s wins',T2);
                    app.Finalist2 = T2;
                    app.Label2.Text = sprintf('Finalist #1: %s',T2);
                end
                disp(app.Label2.Text);
            elseif app.match_index == 47
                while(res_out == 3)
                    res_out = randsrc(1,1,[1,2,3;prob]);
                end
                if res_out == 1
                    app.Label3.Text = sprintf('%s wins',T1);
                    app.Finalist2 = T1;
                    app.Label2.Text = sprintf('Finalist #2: %s',T1);
                elseif res_out == 2
                    app.Label3.Text = sprintf('%s wins',T2);
                    app.Finalist2 = T2;
                    app.Label2.Text = sprintf('Finalist #2: %s',T2);
                end
                disp(app.Label2.Text);
            elseif app.match_index == 48
                while(res_out == 3)
                    res_out = randsrc(1,1,[1,2,3;prob]);
                end
                if res_out == 1
                    app.Label3.Text = sprintf('%s wins the world cup 2019',T1);
                    app.Winner = T1;
                    app.Label2.Text = sprintf('Runner-up is  %s',T2);
                    
                elseif res_out == 2
                    app.Label3.Text = sprintf('%s wins the world cup 2019',T2);
                    app.Winner = T2;
                    app.Label2.Text = sprintf('Runner-up is  %s',T1);
                end
                disp(app.Label3.Text);
                disp(app.Label2.Text);
                app.ResetButton = 'off';
            end
        end


    end
    
    methods (Access = public)
    
        function loaddata(app,selectedButton,WhereValue,TimeValue)
            if strcmp(selectedButton,'All Teams')
                if strcmp(WhereValue,'ALl Competitions')
                    workbookFile = 'TeamPerformances.xlsx';
                    if strcmp(TimeValue,'Since Begining')
                        sheetName='All';
                        endRow = 28;
                    elseif strcmp(TimeValue,'Last 10Y')
                        sheetName='10Y';
                        endRow = 22;
                    elseif strcmp(TimeValue,'Last 5Y')
                        sheetName='5Y';
                        endRow = 21;
                    elseif strcmp(TimeValue,'Last 4Y')
                        sheetName='4Y';
                        endRow = 19;                       
                    elseif strcmp(TimeValue,'Last 3Y')
                        sheetName='3Y';
                        endRow = 19;
                    elseif strcmp(TimeValue,'Last 2Y')
                        sheetName='2Y';
                        endRow = 19;
                    elseif strcmp(TimeValue,'Last 1Y')
                        sheetName='1Y';
                        endRow = 19;
                    end
                elseif strcmp(WhereValue,'In WorldCup')
                    workbookFile = 'TeamPerformancesInWorldCup.xlsx';
                    if strcmp(TimeValue,'Since Begining')
                        sheetName='All';
                        endRow = 21;
                    elseif strcmp(TimeValue,'Last 10Y')
                        sheetName='10Y';
                        endRow = 18;
                    elseif strcmp(TimeValue,'Last 5Y')
                        sheetName='5Y';
                        endRow = 15;
                    elseif strcmp(TimeValue,'Last 4Y')
                        sheetName='4Y';
                        endRow = 15;
                    end
                elseif srtcmp(WhereValue,'In England')
                    workbookFile = 'TeamPerformancesInEngland.xlsx';
                    if strcmp(TimeValue,'Since Begining')
                        sheetName='All';
                        endRow = 17;
                    elseif strcmp(TimeValue,'Last 10Y')
                        sheetName='10Y';
                        endRow = 11;
                    elseif strcmp(TimeValue,'Last 5Y')
                        sheetName='5Y';
                        endRow = 11;
                    elseif strcmp(TimeValue,'Last 4Y')
                        sheetName='4Y';
                        endRow = 11;                       
                    elseif strcmp(TimeValue,'Last 3Y')
                        sheetName='3Y';
                        endRow = 11;
                    elseif strcmp(TimeValue,'Last 2Y')
                        sheetName='2Y';
                        endRow = 11;
                    elseif strcmp(TimeValue,'Last 1Y')
                        sheetName='1Y';
                        endRow = 4;
                    end
                    
                end
                app.Result_Mat = dataImport(workbookFile,sheetName,endRow);
                app.Result_Tab = [];
            elseif strcmp(selectedButton,'The Opponent')
                if strcmp(WhereValue,'All Competitions')
                    workbookFile = 'TeamvsTeam.xlsx';
                    if strcmp(TimeValue,'Since Begining')
                        sheetName='All';
                        endrow = 677;
                    elseif strcmp(TimeValue,'Last 10Y')
                        sheetName='10Y';
                        endRow = 501;
                    elseif strcmp(TimeValue,'Last 5Y')
                        sheetName='5Y';
                        endRow = 393;
                    elseif strcmp(TimeValue,'Last 4Y')
                        sheetName='4Y';
                        endRow = 373;                       
                    elseif strcmp(TimeValue,'Last 3Y')
                        sheetName='3Y';
                        endRow = 325;
                    elseif strcmp(TimeValue,'Last 2Y')
                        sheetName='2Y';
                        endRow = 285;
                    elseif strcmp(TimeValue,'Last 1Y')
                        sheetName='1Y';
                        endRow = 205;
                    end
                elseif strcmp(WhereValue,'In WorldCup')
                    workbookFile = 'TeamvsTeamInWorldCup.xlsx';
                    if strcmp(TimeValue,'Since Begining')
                        sheetName='All';
                        endRow = 481;
                    elseif strcmp(TimeValue,'Last 10Y')
                        sheetName='10Y';
                        endRow = 309;
                    elseif strcmp(TimeValue,'Last 5Y')
                        sheetName='5Y';
                        endRow = 189;
                    elseif strcmp(TimeValue,'Last 4Y')
                        sheetName='4Y';
                        endRow = 189;
                    end
                elseif srtcmp(WhereValue,'In England')
                    workbookFile = 'TeamvsTeamInEngland.xlsx';
                    if strcmp(TimeValue,'Since Begining')
                        sheetName='All';
                        endRow = 241;
                    elseif strcmp(TimeValue,'Last 10Y')
                        sheetName='10Y';
                        endRow = 97;
                    elseif strcmp(TimeValue,'Last 5Y')
                        sheetName='5Y';
                        endRow = 77;
                    elseif strcmp(TimeValue,'Last 4Y')
                        sheetName='4Y';
                        endRow = 77;                       
                    elseif strcmp(TimeValue,'Last 3Y')
                        sheetName='3Y';
                        endRow = 77;
                    elseif strcmp(TimeValue,'Last 2Y')
                        sheetName='2Y';
                        endRow = 73;
                    elseif strcmp(TimeValue,'Last 1Y')
                        sheetName='1Y';
                        endRow = 9;
                    end 
                end
                app.Result_Tab = dataImportTvTWC1(workbookFile,sheetName,endRow);
                app.Result_Mat = [];
                
            end
                    
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.WorldCupSimulatorUIFigure.Name = 'ODI Cricket world cup 2019 Simulator';
          clc
          warning off;
          app.Label.Text = 'Welcome To MATLAB Predictor';
          app.Label2.Visible = 'off';
          app.Label3.Visible = 'off';
          
          app.UIAxes1.Visible = 'off';
          app.UIAxes2.Visible = 'off';
          app.UIAxes3.Visible = 'off';
          app.StopButton.Enable = 'off';
          
          
          app.WhereDropDown.Enable = 'on';
          app.TimeSpanDropDown.Enable = 'on';
          app.GetDataButton.Enable = 'on';
          app.ResetButton.Enable = 'off';
          app.StartButton.Enable = 'off';
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            selectedButton = app.PerformanceAgainstButtonGroup.SelectedObject.Text;
            WhereValue = app.WhereDropDown.Value;
            TimeValue = app.TimeSpanDropDown.Value;
            app.AllTeamsButton.Enable = 'off';
            app.TheOpponentButton.Enable = 'off';
            app.WhereDropDown.Enable = 'off';
            app.TimeSpanDropDown.Enable = 'off';
            app.GetDataButton.Enable = 'off';
            app.ResetButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            loaddata(app,selectedButton,WhereValue,TimeValue);
            disp('Data Fetched')
        end

        % Selection changed function: PerformanceAgainstButtonGroup
        function PerformanceAgainstButtonGroupSelectionChanged(app, event)
            %selectedButton = app.PerformanceAgainstButtonGroup.SelectedObject;
            app.AllTeamsButton.Enable = 'off';
            app.TheOpponentButton.Enable = 'off';
            app.WhereDropDown.Enable = 'on';
            app.TimeSpanDropDown.Enable = 'on';
            app.GetDataButton.Enable = 'on';
            app.StartButton.Enable = 'off';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.AllTeamsButton.Enable = 'on';
            app.TheOpponentButton.Enable = 'on';
            app.WhereDropDown.Enable = 'on';
            app.TimeSpanDropDown.Enable = 'on';
            app.GetDataButton.Enable = 'off';
            app.ResetButton.Enable = 'on';
            app.StartButton.Enable = 'off';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StopButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.Label2.Visible = 'on';
            app.Label3.Visible = 'on';
            delete(app.DialogApp)
            
            cond = true;
            while cond == true
                app.match_index=app.match_index + 1;
                if strcmp(app.StopButton.Enable,'off') && (app.match_index <= 45)
                    cond = false;
                end
                if app.match_index == 46
                    app.StopButton.Enable = 'off';
                    [~,ind] = sort(app.points_table(:,5),'descend');
                    app.tdata=table(app.WC_Teams(ind),app.points_table(ind,1),app.points_table(ind,2),...
                        app.points_table(ind,3),app.points_table(ind,4),app.points_table(ind,5));
                    app.SFinalist = app.tdata{1:4,1};
                    app.DialogApp = PointsTable(app, app.tdata, ind');
                    pause(10);
                elseif app.match_index >= 49
                    break;
                end
                PlayMatch(app,app.match_index)
                pause(2);
            end
        
            
        end

        % Value changed function: TimeSpanDropDown
        function TimeSpanDropDownValueChanged(app, event)
            %value = app.TimeSpanDropDown.Value;
            app.AllTeamsButton.Enable = 'off';
            app.TheOpponentButton.Enable = 'off';
            app.WhereDropDown.Enable = 'off';
            app.TimeSpanDropDown.Enable = 'off';
            app.GetDataButton.Enable = 'on';
            app.ResetButton.Enable = 'on';
            app.StartButton.Enable = 'off';
        end

        % Value changed function: WhereDropDown
        function WhereDropDownValueChanged(app, event)
            %value = app.WhereDropDown.Value;
            app.AllTeamsButton.Enable = 'off';
            app.TheOpponentButton.Enable = 'off';
            app.WhereDropDown.Enable = 'off';
            app.TimeSpanDropDown.Enable = 'on';
            app.GetDataButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            if strcmp(app.WhereDropDown.Value, 'In WorldCup')
                app.TimeSpanDropDown.Items =  {'Since Begining', 'Last 10Y', 'Last 5Y', 'Last 4Y',...
                    'Last 3Y', 'Last 2Y', 'Last 1Y' };
            else
                app.TimeSpanDropDown.Items =  {'Since Begining', 'Last 10Y', 'Last 5Y', 'Last 4Y',...
                    'Last 3Y', 'Last 2Y', 'Last 1Y' };
            end
            app.TimeSpanDropDown.Value = 'Since Begining';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WorldCupSimulatorUIFigure = uifigure;
            app.WorldCupSimulatorUIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.WorldCupSimulatorUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = {'[ ]'};
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = {'[ ]'};
            app.UIAxes3 = uiaxes(app.WorldCupSimulatorUIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = {'[]'};
            app.UIAxes3.YTick = [];
            app.PerformanceAgainstButtonGroup = uibuttongroup(app.WorldCupSimulatorUIFigure);
            app.PerformanceAgainstButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PerformanceAgainstButtonGroupSelectionChanged, true);
            app.PerformanceAgainstButtonGroup.Title = 'Performance Against';
            app.AllTeamsButton = uiradiobutton(app.PerformanceAgainstButtonGroup);
            app.AllTeamsButton.Text = 'All Teams';
            app.AllTeamsButton.Value = true;
            app.TheOpponentButton = uiradiobutton(app.PerformanceAgainstButtonGroup);
            app.TheOpponentButton.Text = 'The Opponent';
            app.WhereDropDownLabel = uilabel(app.WorldCupSimulatorUIFigure);
            app.WhereDropDownLabel.Text = 'Where';
            app.WhereDropDown = uidropdown(app.WorldCupSimulatorUIFigure);
            app.WhereDropDown.Items = {'All Competitions', 'In WorldCup', 'In England', 'Option 4'};
            app.WhereDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereDropDownValueChanged, true);
            app.WhereDropDown.Value = 'All Competitions';
            app.TimeSpanDropDownLabel = uilabel(app.WorldCupSimulatorUIFigure);
            app.TimeSpanDropDownLabel.Text = 'Time Span';
            app.TimeSpanDropDown = uidropdown(app.WorldCupSimulatorUIFigure);
            app.TimeSpanDropDown.Items = {'Since Begining', 'Last 10Y', 'Last 5Y', 'Last 4Y', 'Last 3Y', 'Last 2Y', 'Last 1Y'};
            app.TimeSpanDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeSpanDropDownValueChanged, true);
            app.TimeSpanDropDown.Value = 'Since Begining';
            app.StartButton = uibutton(app.WorldCupSimulatorUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.WorldCupSimulatorUIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.WorldCupSimulatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GetDataButton = uibutton(app.WorldCupSimulatorUIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.Label = uilabel(app.WorldCupSimulatorUIFigure);
            app.Label2 = uilabel(app.WorldCupSimulatorUIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.WorldCupSimulatorUIFigure);
            app.Label3.Text = 'Label3';
            app.Label_2 = uilabel(app.WorldCupSimulatorUIFigure);
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.WorldCupSimulatorUIFigure, 'text');
            app.EditField.Value = 'ODI Cricket world cup 2019 Simulator';
            app.UIAxes1 = uiaxes(app.WorldCupSimulatorUIFigure);
            app.UIAxes1.XTick = [];
            app.UIAxes1.XTickLabel = {'[]'};
            app.UIAxes1.YTick = [];
            app.UIAxes1.YTickLabel = {'[]'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ODI_2019_PREDICTOR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WorldCupSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WorldCupSimulatorUIFigure)
        end
    end
end